classdef EvolutiveSysAnalyze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CurrentRobotFitness             matlab.ui.control.UIAxes
        SensorActivation                matlab.ui.control.UIAxes
        AverageRobotFitness             matlab.ui.control.UIAxes
        LinearVelocity                  matlab.ui.control.UIAxes
        AngularVelocity                 matlab.ui.control.UIAxes
        RotationTime                    matlab.ui.control.UIAxes
        ControlSimulationLabel          matlab.ui.control.Label
        SwitchStart                     matlab.ui.control.Switch
        SimulationStatus                matlab.ui.control.Lamp
        SwitchPause                     matlab.ui.control.Switch
        GenesLabel                      matlab.ui.control.Label
        PopulationfitnessLabel          matlab.ui.control.Label
        SensorAngleLabel                matlab.ui.control.Label
        ipv4Label                       matlab.ui.control.Label
        ipv4EditField                   matlab.ui.control.EditField
        ROSstatus                       matlab.ui.control.Lamp
        ROSLabel                        matlab.ui.control.Label
        SimulationLabel                 matlab.ui.control.Label
        ConnectROSButton                matlab.ui.control.Button
        SensorAngle                     matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        FixedGenesTab                   matlab.ui.container.Tab
        SetFixedGenesLabel              matlab.ui.control.Label
        SensorActivationValue           matlab.ui.control.NumericEditField
        SensorActivationCheckBox        matlab.ui.control.CheckBox
        LinearVelocityCheckBox          matlab.ui.control.CheckBox
        RotationTimeCheckBox            matlab.ui.control.CheckBox
        AngularVelocityCheckBox         matlab.ui.control.CheckBox
        SensorAngleCheckBox             matlab.ui.control.CheckBox
        LinearVelocityValue             matlab.ui.control.NumericEditField
        RotationTimeValue               matlab.ui.control.NumericEditField
        AngularVelocityValue            matlab.ui.control.NumericEditField
        SensorAngleValue                matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        dgsLabel                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        dgLabel                         matlab.ui.control.Label
        PressureTab                     matlab.ui.container.Tab
        TypesofPressureLabel            matlab.ui.control.Label
        PredationCheckBox               matlab.ui.control.CheckBox
        BackMutationPreventionCheckBox  matlab.ui.control.CheckBox
        PredationValue                  matlab.ui.control.NumericEditField
        genLabel                        matlab.ui.control.Label
        EvolutionTab                    matlab.ui.container.Tab
        MutationLabel                   matlab.ui.control.Label
        MutationCheckBox                matlab.ui.control.CheckBox
        MutationValue                   matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        CrossingCheckBox                matlab.ui.control.CheckBox
        NeutralizationCheckBox          matlab.ui.control.CheckBox
        NeutralizationValue             matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        QtygenerationsinmeanCheckBox    matlab.ui.control.CheckBox
        NeutralizationValue_2           matlab.ui.control.NumericEditField
        QtybestrobotsCheckBox           matlab.ui.control.CheckBox
        NeutralizationValue_3           matlab.ui.control.NumericEditField
        CrossingLabel_2                 matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        AnalyzeEvolutionLabel           matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        ManageDataTab                   matlab.ui.container.Tab
        ImportEvolution_2               matlab.ui.control.Button
        ImportEvolution_3               matlab.ui.control.Button
        ExportGenerationsDataLabel      matlab.ui.control.Label
        ImportGenerationsDataLabel      matlab.ui.control.Label
        SaveGraphsTab                   matlab.ui.container.Tab
        SavealltoPDFfileLabel           matlab.ui.control.Label
        SavesinglegraphLabel            matlab.ui.control.Label
        SelectGraphImage                matlab.ui.control.DropDown
        SaveImage                       matlab.ui.control.Button
        SavePDF                         matlab.ui.control.Button
        ImportEvolution_4               matlab.ui.control.Button
        ReturntothisgenerationLabel     matlab.ui.control.Label
        SimulationParametersLabel       matlab.ui.control.Label
        Tree                            matlab.ui.container.Tree
        GraphLinesNode                  matlab.ui.container.TreeNode
        AverageNode                     matlab.ui.container.TreeNode
        Robot1Node                      matlab.ui.container.TreeNode
        Robot2Node                      matlab.ui.container.TreeNode
        GraphvisualizationLabel         matlab.ui.control.Label
        CurrentGenerationSpinnerLabel   matlab.ui.control.Label
        CurrentGenerationSpinner        matlab.ui.control.Spinner
        DatamanagementLabel             matlab.ui.control.Label
        ManageGenerationLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        SensorAnglePolar % Polar graph (Sensor Angle)
        myTimer
        infosub
        % Variables to plotting
        last5Fit = zeros(6,5);
        x    = 0;
        yFt  = zeros(7,1);
        yAv  = zeros(7,1);
        ySA  = zeros(7,1);
        yLV  = zeros(7,1);
        yAV  = zeros(7,1);
        yRT  = zeros(7,1);
        ySAn = zeros(7,1);
        evolConfigPub;
        controlSimPub;
        tempFilename; % Temp file directory
    end
    
    methods (Access = private)


        function [] = updatePlots(~, ~, info,app)
            disp('updatePlots');
            if(robotics.ros.internal.Global.isNodeActive)
%                 app.x(info.Generation) = info.Generation;

%                 infoDataName = {'SensorActivation' ;'LinearVelocity' ;'AngularVelocity'; 'RotationTime';'Fitness'; 'SensorAngle'};
                infoDataInitials = {'Ft','Av','SA','LV','AV','RT','SAn'};
                %----- Update Average Fitness Value -----%
                for i=1:6% Update historic fitness matrix
                    for j=1:4
                        app.last5Fit(i,j) = app.last5Fit(i,j+1);
                    end
                    app.last5Fit(i,5) = info.Fitness(i);
                end

                % Update robotX average fitness
                nextYAv = zeros(7,1);
                for robotNum=1:6 % Update average values for the current population
                    nextYAv(robotNum) = sum(app.last5Fit(robotNum,:));

                    if(info.Generation>=5)
                        nextYAv(robotNum) = nextYAv(robotNum)/5;
                    else
                        nextYAv(robotNum) = nextYAv(robotNum)/info.Generation;
                    end
                end
                nextYAv(7) = sum(nextYAv(1:6))/6;

%                 if(info.Generation==1)
                    app.yAv = nextYAv;
%                 else
%                     app.yAv = [app.yAv  nextYAv];
%                 end

               %----- Update Y data for each graphs -----%
%                 for i=1:6
%                     nextY = zeros(6,1);
%                     eval(['nextY = info.' char(infoDataName(i)) ';']);
% 
%                     nextY = [nextY ; mean(nextY)];
% 
%                     if(info.Generation==1)
%                         eval(['app.y' char(infoDataInitials(i)) ' = nextY;']);
%                     else
%                         eval([ 'app.y' char(infoDataInitials(i)) ' = [app.y' char(infoDataInitials(i)) ' nextY];']);
%                     end
%                 end
                %----- Write on temp file -----%
                fileID = fopen(app.tempFilename, 'a+' );
                fprintf( fileID, 'gen%d:\n',info.Generation);
                for i=1:6
                    fprintf( fileID, '\trobot%d: %f %f %f %f %f %f %f\n',...
                        i, info.Fitness(i), app.yAv(i), info.SensorActivation(i), info.LinearVelocity(i), info.AngularVelocity(i), info.RotationTime(i), info.SensorAngle(i));
                end
                fclose(fileID);
                
                %----- Update chromosome graphs -----%
%                 plotARF = plot(app.AverageRobotFitness,app.x,app.yAv);
%                 set(plotARF(7),'Color','r','linew',2);
%                 plotCRF = plot(app.CurrentRobotFitness,app.x,app.yFt);
%                 set(plotCRF(7),'Color','r','linew',2);
%                 plotSA = plot(app.SensorActivation,app.x,app.ySA);
%                 set(plotSA(7),'Color','r','linew',2);
%                 plotLV = plot(app.LinearVelocity,app.x,app.yLV);
%                 set(plotLV(7),'Color','r','linew',2);
%                 plotAV = plot(app.AngularVelocity,app.x,app.yAV);
%                 set(plotAV(7),'Color','r','linew',2);
%                 plotRT = plot(app.RotationTime,app.x,app.yRT);
%                 set(plotRT(7),'Color','r','linew',2);
%                 plotSAn = plot(app.SensorAngle,app.x,app.ySAn);
%                 set(plotSAn(7),'Color','r','linew',2);
%                 plotPolarSAn = plot(app.SensorAnglePolar,(app.ySAn.*(pi/180))',app.x,(-(app.ySAn).*(pi/180))',app.x,zeros(info.Generation,1),app.x);
%                 app.SensorAnglePolar.ThetaZeroLocation = 'top';
%                 set(plotPolarSAn(7),'Color','r','linew',2);
%                 set(plotPolarSAn(14),'Color','r','linew',2);
%                 set(plotPolarSAn(15),'Color','r','linew',2);

                %----- Plot from temp file -----%
                fileID = fopen(app.tempFilename, 'r' );
                
                short_word = fscanf(fileID, '%s+');
                while(short_word~="-----Data-----")% Ignore start comment
                    short_word = fscanf(fileID, '%s+');
                end
                
                % Setting each variable to zero
                for i=1:7
                    eval([ 'clear app.y' char(infoDataInitials(i))]);
                    eval([ 'app.y' char(infoDataInitials(i)) ' = [.0;.0;.0;.0;.0;.0];' ]);
                end
                app.x = 0;
                
                currGeneration = '0';
                % Loop through each generation

                for i=1:7
                   eval([ 'clear app.y' char(infoDataInitials(i)) ]);
                end
                
                firstLoop = true;
                
                while(str2num(currGeneration)~=info.Generation)


                    short_word = fscanf(fileID, '%s+');
                    while(size(short_word,2)~=5 && short_word(1:3)~="gen")
                        short_word = fscanf(fileID, '%s+');
                    end
                    currGeneration = short_word(4)
                    
                    % Get data from each robot in generation
                    formatData = '\trobot%d: %f %f %f %f %f %f %f';
                    sizeData = [8 6];
                    generationData = fscanf(fileID, formatData,sizeData)'
                    
                    app.x = [ app.x currGeneration ];
                    
                    for i=1:7
%                         eval([ 'clear app.y'  char(infoDataInitials(i)) ]);
%                         generationData(:,i+1)
%                         eval([ 'app.y' char(infoDataInitials(i)) ' = [ app.y' char(infoDataInitials(i)) ' ' char(generationData(:,i+1)) ' ];' ]);
%                         eval([ '[ app.y' char(infoDataInitials(i)) ' ' char(generationData(:,i+1)) ' ]' ]);
                        newData = generationData(:,i+1)'
                        eval([ 'app.y' char(infoDataInitials(i)) ' = [ app.y' char(infoDataInitials(i)) ' ' char(newData) ' ];' ]);
                        eval([ '[ app.y' char(infoDataInitials(i)) ' ' char(newData) ' ]' ]);

%                         eval([ 'app.y' char(infoDataInitials(i)) ' = [ app.y' char(infoDataInitials(i)) ' [1;2;3;4;5;6] ];' ]);
%                         eval([ '[ app.y' char(infoDataInitials(i)) ' [1;2;3;4;5;6] ]' ]);

%                         app.yFt  = [ app.yFt generationData(:,i+1)];
%                         [ app.yFt generationData(:,i+1)]
                    end
                end
                
                fclose(fileID);
            end
        end
        
        function [] = publishEvolutiveSystemConfiguration(app)
            if(robotics.ros.internal.Global.isNodeActive)
                app.evolConfigPub = rospublisher('/evolutive_config','obstacle_avoidance_simulation/EvolutiveSystemConfiguration');
                msg = rosmessage(app.evolConfigPub);
                
                if(app.SensorActivationValue.Enable=="on")
                    msg.SensorActivation =  single(app.SensorActivationValue.Value);
                else
                    msg.SensorActivation =  single(-1);
                end
                if(app.LinearVelocityValue.Enable=="on")
                    msg.LinearVelocity =  single(app.LinearVelocityValue.Value);
                else
                    msg.LinearVelocity =  single(-1);
                end
                if(app.AngularVelocityValue.Enable=="on")
                    msg.AngularVelocity =  single(app.AngularVelocityValue.Value);
                else
                    msg.AngularVelocity =  single(-1);
                end
                if(app.RotationTimeValue.Enable=="on")
                    msg.RotationTime =  single(app.RotationTimeValue.Value);
                else
                    msg.RotationTime =  single(-1);
                end
                if(app.SensorAngleValue.Enable=="on")
                    msg.SensorAngle =  single(app.SensorAngleValue.Value);
                else
                    msg.SensorAngle =  single(-1);
                end
                if(app.PredationValue.Enable=="on")
                    msg.Predation =  int32(app.PredationValue.Value);
                else
                    msg.Predation =  int32(-1);
                end
                if(app.MutationValue.Enable=="on")
                    msg.Mutation =  single(app.MutationValue.Value);
                else
                    msg.Mutation =  single(-1);
                end
                if(app.NeutralizationValue.Enable=="on")
                    msg.Neutralization =  single(app.NeutralizationValue.Value);
                else
                    msg.Neutralization =  single(-1);
                end
                
                msg.BackMutationPrevention = app.BackMutationPreventionCheckBox.Value;
                msg.Crossing = app.CrossingCheckBox.Value;
                
                send(app.evolConfigPub,msg);
            end
        end
        
        function [] = publishControlSimulation(app)
            app.controlSimPub = rospublisher('/control_simulation','obstacle_avoidance_simulation/ControlSimulation');
            msg = rosmessage(app.controlSimPub);
            
            if(app.SwitchStart.Value=="Stop")
                msg.SimulationState = 0;
            else
                if(app.SwitchPause.Value=="Run")
                    msg.SimulationState = 1;
                else
                    msg.SimulationState = 2;
                end
            end
            
            send(app.controlSimPub,msg);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Draw Sensor Angle Polar Plot
            app.SensorAnglePolar = polaraxes(app.UIFigure);
            app.SensorAnglePolar.Units = 'pixels';
            app.SensorAnglePolar.Position = [340 20 200 200];
            app.SensorAnglePolar.ThetaZeroLocation = 'top';
            
            % Check ROS Connection
            if(~robotics.ros.internal.Global.isNodeActive)
                rosinit(app.ipv4EditField.Value);
            end
            
            if(robotics.ros.internal.Global.isNodeActive)
                app.ROSstatus.Color='g';
                app.ConnectROSButton.Text='Disconnect ROS';
                app.publishEvolutiveSystemConfiguration;
                app.infosub = rossubscriber('/simulation_info',{@app.updatePlots,app});
            else
                app.ROSstatus.Color='r';
                app.ConnectROSButton.Text='Connect ROS';
            end
            
            %----- Create temp.txt file -----%
            dir = mfilename('fullpath');
            dir = dir(:,1:(size(dir,2)-20));
            filename = fullfile(dir, 'temp.txt');
            app.tempFilename = filename;
            fileID = fopen( filename, 'wt' );
            fprintf( fileID, '-----Evolutionary System Temporary Data-----\n');
            fprintf( fileID, 'gen<generation>:\n');
            fprintf( fileID, '\trobot<robotNumber>: <actualFitness> <averageFitness> <sensorActivation> <linearVelocity> <rotationTime> <angularVelocity> <sensorAngle>\n\n');
            fprintf( fileID, '-----Data-----\n');
            fclose(fileID);
            
        end

        % Button pushed function: ConnectROSButton
        function ConnectROSButtonPushed(app, event)
            robotics.ros.internal.Global.isNodeActive
            
            if(robotics.ros.internal.Global.isNodeActive==0)
                rosinit(app.ipv4EditField.Value);
                if(robotics.ros.internal.Global.isNodeActive==1)
                    app.ROSstatus.Color='g';
                    app.ConnectROSButton.Text='Disconnect ROS';
                    
                    app.infosub = rossubscriber('simulation_info');
                end
            else
                rosshutdown;
                if(robotics.ros.internal.Global.isNodeActive==0)
                    app.ROSstatus.Color='r';
                    app.ConnectROSButton.Text='Connect ROS';
                end
            end
        end

        % Value changed function: SwitchStart
        function SwitchStartValueChanged(app, event)
            value = app.SwitchStart.Value;
            
            app.publishControlSimulation;
            if(value=="Start")
                app.SwitchPause.Enable=1;
                app.SwitchPause.Value="Run";
                app.SimulationStatus.Color='g';
            else
                app.SwitchPause.Enable=0;
                app.SwitchPause.Value="Run";
                app.SimulationStatus.Color='r';
            end
            
        end

        % Value changed function: SwitchPause
        function SwitchPauseValueChanged(app, event)
            value = app.SwitchPause.Value;
            
            app.publishControlSimulation;
            if(value=="Run")
                app.SimulationStatus.Color='g';
            else
                app.SimulationStatus.Color='y';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            rosshutdown;
        end

        % Value changed function: SensorActivationCheckBox
        function SensorActivationCheckBoxValueChanged(app, event)
            value = app.SensorActivationCheckBox.Value;
            
            if(value)
                app.SensorActivationValue.Enable=1;
            else
                app.SensorActivationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: LinearVelocityCheckBox
        function LinearVelocityCheckBoxValueChanged(app, event)
            value = app.LinearVelocityCheckBox.Value;
            
            if(value)
                app.LinearVelocityValue.Enable=1;
            else
                app.LinearVelocityValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: RotationTimeCheckBox
        function RotationTimeCheckBoxValueChanged(app, event)
            value = app.RotationTimeCheckBox.Value;
            
            if(value)
                app.RotationTimeValue.Enable=1;
            else
                app.RotationTimeValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: AngularVelocityCheckBox
        function AngularVelocityCheckBoxValueChanged(app, event)
            value = app.AngularVelocityCheckBox.Value;
            
            if(value)
                app.AngularVelocityValue.Enable=1;
            else
                app.AngularVelocityValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: SensorAngleCheckBox
        function SensorAngleCheckBoxValueChanged(app, event)
            value = app.SensorAngleCheckBox.Value;
            
            if(value)
                app.SensorAngleValue.Enable=1;
            else
                app.SensorAngleValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: BackMutationPreventionCheckBox
        function BackMutationPreventionCheckBoxValueChanged(app, event)
            %             value = app.BackMutationPreventionCheckBox.Value;
            app.publishEvolutiveSystemConfiguration;
        end

        % Value changed function: PredationCheckBox
        function PredationCheckBoxValueChanged(app, event)
            value = app.PredationCheckBox.Value;
            
            if(value)
                app.PredationValue.Enable=1;
            else
                app.PredationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: MutationCheckBox
        function MutationCheckBoxValueChanged(app, event)
            value = app.MutationCheckBox.Value;
            
            if(value)
                app.MutationValue.Enable=1;
            else
                app.MutationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: CrossingCheckBox
        function CrossingCheckBoxValueChanged(app, event)
            %             value = app.CrossingCheckBox.Value;
            app.publishEvolutiveSystemConfiguration;
        end

        % Value changed function: NeutralizationCheckBox
        function NeutralizationCheckBoxValueChanged(app, event)
            value = app.NeutralizationCheckBox.Value;
            
            if(value)
                app.NeutralizationValue.Enable=1;
            else
                app.NeutralizationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: SensorActivationValue
        function SensorActivationValueValueChanged(app, event)
            %             value = app.SensorActivationValue.Value;
            if(app.SensorActivationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: LinearVelocityValue
        function LinearVelocityValueValueChanged(app, event)
            %             value = app.LinearVelocityValue.Value;
            if(app.LinearVelocityValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: RotationTimeValue
        function RotationTimeValueValueChanged(app, event)
            %             value = app.RotationTimeValue.Value;
            if(app.RotationTimeValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: AngularVelocityValue
        function AngularVelocityValueValueChanged(app, event)
            %             value = app.AngularVelocityValue.Value;
            if(app.AngularVelocityValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: SensorAngleValue
        function SensorAngleValueValueChanged(app, event)
            %             value = app.SensorAngleValue.Value;
            if(app.SensorAngleValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: PredationValue
        function PredationValueValueChanged(app, event)
            %             value = app.PredationValue.Value;
            if(app.PredationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: MutationValue
        function MutationValueValueChanged(app, event)
            %             value = app.MutationValue.Value;
            if(app.MutationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: NeutralizationValue
        function NeutralizationValueValueChanged(app, event)
            value = app.NeutralizationValue.Value;
            if(app.NeutralizationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Button pushed function: SaveImage
        function SaveImageButtonPushed(app, event)
            imageName = inputdlg('Write the image name:');
            
            if(isempty(imageName))
                fig = figure('name',[app.SelectGraphImage.Value '.png']);
            else
                fig = figure('name',[imageName{1} '.png']);
            end

            figure(fig);
            subplot(211);
            
            %select a folder
            pathname = uigetdir(pwd, 'Select the folder where you want to save.');
            if(pathname~=0)
                pathname = [pathname '/'];
            
                switch(app.SelectGraphImage.Value)
                    case 'Current Fitness'
                        plotToSave = plot(app.x,app.yFt);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');
                    case 'Average Fitness'
                        plotToSave = plot(app.x,app.yAv);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');
                    case 'Sensor Activation'
                        plotToSave = plot(app.x,app.ySA);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Distance (m)');
                        xlabel(gca,'Generation');
                    case 'Linear Velocity'
                        plotToSave = plot(app.x,app.yLV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'L. Velocity (m/s)');
                        xlabel(gca,'Generation');
                    case 'Angular Velocity'
                        plotToSave = plot(app.x,app.yAV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'A. Velocity (degree/s)');
                        xlabel(gca,'Generation');
                    case 'Rotation Time'
                        plotToSave = plot(app.x,app.yRT);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Time (seg)');
                        xlabel(gca,'Generation');
                    case 'Sensor Angle'
                        plotToSave = plot(app.x,app.ySAn);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Angle (degree)');
                        xlabel(gca,'Generation');
                end

                if(isempty(imageName))
                    saveas(fig,[ pathname app.SelectGraphImage.Value '.png']);
                else
                    saveas(fig,[ pathname imageName{1} '.png']);
                end
            end
        end

        % Button pushed function: SavePDF
        function SavePDFButtonPushed(app, event)
            imageName = inputdlg('Write the pdf name:');
            
            if(isempty(imageName))
                fig = figure('name',['allGraphs.pdf']);
            else
                fig = figure('name',[imageName{1} '.pdf']);
            end

            figure(fig);
            
            %select a folder
            pathname = uigetdir(pwd, 'Select the folder where you want to save.');
            if(pathname~=0)
                pathname = [pathname '/'];
            
                        subplot(511);
                        plotToSave = plot(app.x,app.yFt);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Current Fitness');
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');
                        
                        subplot(512);
                        plotToSave = plot(app.x,app.yAv);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Average Fitness');
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');

                        subplot(525);
                        plotToSave = plot(app.x,app.ySA);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Sensor Activation');
                        ylabel(gca,'Distance (m)');
                        xlabel(gca,'Generation');
                        
                        subplot(526);
                        plotToSave = plot(app.x,app.yLV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Linear Velocity');
                        ylabel(gca,'L. Velocity (m/s)');
                        xlabel(gca,'Generation');

                        subplot(527);
                        plotToSave = plot(app.x,app.yAV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Angular Velocity');
                        ylabel(gca,'A. Velocity (degree/s)');
                        xlabel(gca,'Generation');

                        subplot(528);
                        plotToSave = plot(app.x,app.yRT);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Rotation Time');
                        ylabel(gca,'Time (seg)');
                        xlabel(gca,'Generation');

                        subplot(529);
                        plotToSave = plot(app.x,app.ySAn);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Sensor Angle');
                        ylabel(gca,'Angle (degree)');
                        xlabel(gca,'Generation');
            end

            if(isempty(imageName))
                saveas(fig,[ pathname 'allGraphs.pdf']);
            else
                saveas(fig,[ pathname imageName{1} '.pdf']);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CurrentRobotFitness = uiaxes(app.UIFigure);
            title(app.CurrentRobotFitness, 'Current Robot Fitness')
            xlabel(app.CurrentRobotFitness, 'Generation')
            ylabel(app.CurrentRobotFitness, 'Fitness')
            app.SensorActivation = uiaxes(app.UIFigure);
            title(app.SensorActivation, 'Sensor Activation')
            xlabel(app.SensorActivation, 'Generation')
            ylabel(app.SensorActivation, 'Distance (m)')
            app.AverageRobotFitness = uiaxes(app.UIFigure);
            title(app.AverageRobotFitness, 'Average Robot Fitness')
            xlabel(app.AverageRobotFitness, 'Generation')
            ylabel(app.AverageRobotFitness, 'Fitness')
            app.LinearVelocity = uiaxes(app.UIFigure);
            title(app.LinearVelocity, 'Linear Velocity')
            xlabel(app.LinearVelocity, 'Generation')
            ylabel(app.LinearVelocity, 'L. Velocity (m/s)')
            app.AngularVelocity = uiaxes(app.UIFigure);
            title(app.AngularVelocity, 'Angular Velocity')
            xlabel(app.AngularVelocity, 'Generation')
            ylabel(app.AngularVelocity, 'A. Velocity (degree/s)')
            app.RotationTime = uiaxes(app.UIFigure);
            title(app.RotationTime, 'Rotation Time')
            xlabel(app.RotationTime, 'Generation')
            ylabel(app.RotationTime, 'Time (seg)')
            app.ControlSimulationLabel = uilabel(app.UIFigure);
            app.ControlSimulationLabel.Text = 'Control Simulation';
            app.SwitchStart = uiswitch(app.UIFigure, 'slider');
            app.SwitchStart.Items = {'Stop', 'Start'};
            app.SwitchStart.ValueChangedFcn = createCallbackFcn(app, @SwitchStartValueChanged, true);
            app.SwitchStart.Value = 'Stop';
            app.SimulationStatus = uilamp(app.UIFigure);
            app.SwitchPause = uiswitch(app.UIFigure, 'slider');
            app.SwitchPause.Items = {'Pause', 'Run'};
            app.SwitchPause.ValueChangedFcn = createCallbackFcn(app, @SwitchPauseValueChanged, true);
            app.SwitchPause.Enable = 'off';
            app.SwitchPause.Value = 'Run';
            app.GenesLabel = uilabel(app.UIFigure);
            app.GenesLabel.Text = 'Genes';
            app.PopulationfitnessLabel = uilabel(app.UIFigure);
            app.PopulationfitnessLabel.Text = 'Population fitness';
            app.SensorAngleLabel = uilabel(app.UIFigure);
            app.SensorAngleLabel.Text = 'Sensor Angle';
            app.ipv4Label = uilabel(app.UIFigure);
            app.ipv4Label.Text = 'ipv4:';
            app.ipv4EditField = uieditfield(app.UIFigure, 'text');
            app.ipv4EditField.Value = '192.168.15.17';
            app.ROSstatus = uilamp(app.UIFigure);
            app.ROSLabel = uilabel(app.UIFigure);
            app.ROSLabel.Text = 'ROS';
            app.SimulationLabel = uilabel(app.UIFigure);
            app.SimulationLabel.Text = 'Simulation';
            app.ConnectROSButton = uibutton(app.UIFigure, 'push');
            app.ConnectROSButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectROSButtonPushed, true);
            app.ConnectROSButton.Text = {'Connect ROS'; ''};
            app.SensorAngle = uiaxes(app.UIFigure);
            title(app.SensorAngle, '')
            xlabel(app.SensorAngle, 'Generation')
            ylabel(app.SensorAngle, 'Angle (degree)')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FixedGenesTab = uitab(app.TabGroup);
            app.FixedGenesTab.Title = 'Fixed Genes';
            app.SetFixedGenesLabel = uilabel(app.FixedGenesTab);
            app.SetFixedGenesLabel.Text = 'Set Fixed Genes';
            app.SensorActivationValue = uieditfield(app.FixedGenesTab, 'numeric');
            app.SensorActivationValue.Limits = [0 Inf];
            app.SensorActivationValue.ValueChangedFcn = createCallbackFcn(app, @SensorActivationValueValueChanged, true);
            app.SensorActivationValue.Enable = 'off';
            app.SensorActivationCheckBox = uicheckbox(app.FixedGenesTab);
            app.SensorActivationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensorActivationCheckBoxValueChanged, true);
            app.SensorActivationCheckBox.Text = 'Sensor Activation';
            app.LinearVelocityCheckBox = uicheckbox(app.FixedGenesTab);
            app.LinearVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearVelocityCheckBoxValueChanged, true);
            app.LinearVelocityCheckBox.Text = 'Linear Velocity';
            app.RotationTimeCheckBox = uicheckbox(app.FixedGenesTab);
            app.RotationTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotationTimeCheckBoxValueChanged, true);
            app.RotationTimeCheckBox.Text = 'Rotation Time';
            app.AngularVelocityCheckBox = uicheckbox(app.FixedGenesTab);
            app.AngularVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityCheckBoxValueChanged, true);
            app.AngularVelocityCheckBox.Text = 'Angular Velocity';
            app.SensorAngleCheckBox = uicheckbox(app.FixedGenesTab);
            app.SensorAngleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensorAngleCheckBoxValueChanged, true);
            app.SensorAngleCheckBox.Text = 'Sensor Angle';
            app.LinearVelocityValue = uieditfield(app.FixedGenesTab, 'numeric');
            app.LinearVelocityValue.ValueChangedFcn = createCallbackFcn(app, @LinearVelocityValueValueChanged, true);
            app.LinearVelocityValue.Enable = 'off';
            app.RotationTimeValue = uieditfield(app.FixedGenesTab, 'numeric');
            app.RotationTimeValue.Limits = [0 Inf];
            app.RotationTimeValue.ValueChangedFcn = createCallbackFcn(app, @RotationTimeValueValueChanged, true);
            app.RotationTimeValue.Enable = 'off';
            app.AngularVelocityValue = uieditfield(app.FixedGenesTab, 'numeric');
            app.AngularVelocityValue.Limits = [0 Inf];
            app.AngularVelocityValue.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityValueValueChanged, true);
            app.AngularVelocityValue.Enable = 'off';
            app.SensorAngleValue = uieditfield(app.FixedGenesTab, 'numeric');
            app.SensorAngleValue.Limits = [0 90];
            app.SensorAngleValue.ValueChangedFcn = createCallbackFcn(app, @SensorAngleValueValueChanged, true);
            app.SensorAngleValue.Enable = 'off';
            app.mLabel = uilabel(app.FixedGenesTab);
            app.mLabel.Text = 'm';
            app.msLabel = uilabel(app.FixedGenesTab);
            app.msLabel.Text = 'm/s';
            app.dgsLabel = uilabel(app.FixedGenesTab);
            app.dgsLabel.Text = 'dg/s';
            app.sLabel = uilabel(app.FixedGenesTab);
            app.sLabel.Text = 's';
            app.dgLabel = uilabel(app.FixedGenesTab);
            app.dgLabel.Text = 'dg';
            app.PressureTab = uitab(app.TabGroup);
            app.PressureTab.Title = 'Pressure';
            app.TypesofPressureLabel = uilabel(app.PressureTab);
            app.TypesofPressureLabel.Text = 'Types of Pressure';
            app.PredationCheckBox = uicheckbox(app.PressureTab);
            app.PredationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PredationCheckBoxValueChanged, true);
            app.PredationCheckBox.Text = 'Predation';
            app.PredationCheckBox.Value = true;
            app.BackMutationPreventionCheckBox = uicheckbox(app.PressureTab);
            app.BackMutationPreventionCheckBox.ValueChangedFcn = createCallbackFcn(app, @BackMutationPreventionCheckBoxValueChanged, true);
            app.BackMutationPreventionCheckBox.Text = 'Back Mutation Prevention';
            app.BackMutationPreventionCheckBox.Value = true;
            app.PredationValue = uieditfield(app.PressureTab, 'numeric');
            app.PredationValue.Limits = [0 Inf];
            app.PredationValue.ValueChangedFcn = createCallbackFcn(app, @PredationValueValueChanged, true);
            app.PredationValue.Value = 10;
            app.genLabel = uilabel(app.PressureTab);
            app.genLabel.Text = 'gen.';
            app.EvolutionTab = uitab(app.TabGroup);
            app.EvolutionTab.Title = 'Evolution';
            app.MutationLabel = uilabel(app.EvolutionTab);
            app.MutationLabel.Text = 'Mutation';
            app.MutationCheckBox = uicheckbox(app.EvolutionTab);
            app.MutationCheckBox.ValueChangedFcn = createCallbackFcn(app, @MutationCheckBoxValueChanged, true);
            app.MutationCheckBox.Text = 'Mutation';
            app.MutationCheckBox.Value = true;
            app.MutationValue = uieditfield(app.EvolutionTab, 'numeric');
            app.MutationValue.Limits = [0 100];
            app.MutationValue.ValueChangedFcn = createCallbackFcn(app, @MutationValueValueChanged, true);
            app.MutationValue.Value = 10;
            app.Label = uilabel(app.EvolutionTab);
            app.Label.Text = '%';
            app.CrossingCheckBox = uicheckbox(app.EvolutionTab);
            app.CrossingCheckBox.ValueChangedFcn = createCallbackFcn(app, @CrossingCheckBoxValueChanged, true);
            app.CrossingCheckBox.Text = 'Crossing (melhor transa com todos)';
            app.CrossingCheckBox.Value = true;
            app.NeutralizationCheckBox = uicheckbox(app.EvolutionTab);
            app.NeutralizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @NeutralizationCheckBoxValueChanged, true);
            app.NeutralizationCheckBox.Text = 'Neutralization';
            app.NeutralizationCheckBox.Value = true;
            app.NeutralizationValue = uieditfield(app.EvolutionTab, 'numeric');
            app.NeutralizationValue.Limits = [0 100];
            app.NeutralizationValue.ValueChangedFcn = createCallbackFcn(app, @NeutralizationValueValueChanged, true);
            app.NeutralizationValue.Value = 50;
            app.Label_2 = uilabel(app.EvolutionTab);
            app.Label_2.Text = '%';
            app.QtygenerationsinmeanCheckBox = uicheckbox(app.EvolutionTab);
            app.QtygenerationsinmeanCheckBox.Text = {'Qty. generations '; 'in mean'};
            app.QtygenerationsinmeanCheckBox.Value = true;
            app.NeutralizationValue_2 = uieditfield(app.EvolutionTab, 'numeric');
            app.NeutralizationValue_2.Limits = [0 100];
            app.NeutralizationValue_2.Value = 7;
            app.QtybestrobotsCheckBox = uicheckbox(app.EvolutionTab);
            app.QtybestrobotsCheckBox.Text = 'Qty. best robots';
            app.QtybestrobotsCheckBox.Value = true;
            app.NeutralizationValue_3 = uieditfield(app.EvolutionTab, 'numeric');
            app.NeutralizationValue_3.Limits = [0 100];
            app.NeutralizationValue_3.Value = 3;
            app.CrossingLabel_2 = uilabel(app.EvolutionTab);
            app.CrossingLabel_2.Text = 'Crossing';
            app.Slider = uislider(app.UIFigure);
            app.AnalyzeEvolutionLabel = uilabel(app.UIFigure);
            app.AnalyzeEvolutionLabel.Text = 'Analyze Evolution';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.ManageDataTab = uitab(app.TabGroup2);
            app.ManageDataTab.Title = 'Manage Data';
            app.ImportEvolution_2 = uibutton(app.ManageDataTab, 'push');
            app.ImportEvolution_2.Text = 'Export';
            app.ImportEvolution_3 = uibutton(app.ManageDataTab, 'push');
            app.ImportEvolution_3.Text = 'Import';
            app.ExportGenerationsDataLabel = uilabel(app.ManageDataTab);
            app.ExportGenerationsDataLabel.Text = 'Export Generations Data';
            app.ImportGenerationsDataLabel = uilabel(app.ManageDataTab);
            app.ImportGenerationsDataLabel.Text = 'Import Generations Data';
            app.SaveGraphsTab = uitab(app.TabGroup2);
            app.SaveGraphsTab.Title = 'Save Graphs';
            app.SavealltoPDFfileLabel = uilabel(app.SaveGraphsTab);
            app.SavealltoPDFfileLabel.Text = 'Save all to PDF file';
            app.SavesinglegraphLabel = uilabel(app.SaveGraphsTab);
            app.SavesinglegraphLabel.Text = 'Save single graph';
            app.SelectGraphImage = uidropdown(app.SaveGraphsTab);
            app.SelectGraphImage.Items = {'Current Fitness', 'Average Fitness', 'Sensor Activation', 'Linear Velocity', 'Angular Velocity', 'Rotation Time', 'Sensor Angle'};
            app.SelectGraphImage.Value = 'Current Fitness';
            app.SaveImage = uibutton(app.SaveGraphsTab, 'push');
            app.SaveImage.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImage.Text = 'Save';
            app.SavePDF = uibutton(app.SaveGraphsTab, 'push');
            app.SavePDF.ButtonPushedFcn = createCallbackFcn(app, @SavePDFButtonPushed, true);
            app.SavePDF.Text = 'Save';
            app.ImportEvolution_4 = uibutton(app.UIFigure, 'push');
            app.ImportEvolution_4.Text = 'Yes';
            app.ReturntothisgenerationLabel = uilabel(app.UIFigure);
            app.ReturntothisgenerationLabel.Text = 'Return to this generation?';
            app.SimulationParametersLabel = uilabel(app.UIFigure);
            app.SimulationParametersLabel.Text = 'Simulation Parameters';
            app.Tree = uitree(app.UIFigure);
            app.GraphLinesNode = uitreenode(app.Tree);
            app.GraphLinesNode.Text = 'Graph Lines';
            app.AverageNode = uitreenode(app.GraphLinesNode);
            app.AverageNode.Text = 'Average';
            app.Robot1Node = uitreenode(app.GraphLinesNode);
            app.Robot1Node.NodeData = 'ref';
            app.Robot1Node.Text = 'Robot1';
            app.Robot2Node = uitreenode(app.GraphLinesNode);
            app.Robot2Node.Text = 'Robot2';
            app.GraphvisualizationLabel = uilabel(app.UIFigure);
            app.GraphvisualizationLabel.Text = 'Graph visualization';
            app.CurrentGenerationSpinnerLabel = uilabel(app.UIFigure);
            app.CurrentGenerationSpinnerLabel.Text = 'Current Generation';
            app.CurrentGenerationSpinner = uispinner(app.UIFigure);
            app.CurrentGenerationSpinner.Limits = [0 Inf];
            app.DatamanagementLabel = uilabel(app.UIFigure);
            app.DatamanagementLabel.Text = 'Data management';
            app.ManageGenerationLabel = uilabel(app.UIFigure);
            app.ManageGenerationLabel.Text = 'Manage Generation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EvolutiveSysAnalyze

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end